classdef APP_MPMM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        AzimutmilsEditField             matlab.ui.control.NumericEditField
        AzimutmilsEditFieldLabel        matlab.ui.control.Label
        LatitudeEditField               matlab.ui.control.NumericEditField
        LatitudeEditFieldLabel          matlab.ui.control.Label
        WINDSwitch                      matlab.ui.control.Switch
        WINDSwitchLabel                 matlab.ui.control.Label
        WindYrichtingmsEditField        matlab.ui.control.NumericEditField
        WindYrichtingmsEditFieldLabel   matlab.ui.control.Label
        WindZrichtingmsEditField        matlab.ui.control.NumericEditField
        WindZrichtingmsEditFieldLabel   matlab.ui.control.Label
        WindXrichtingmsEditField        matlab.ui.control.NumericEditField
        WindXrichtingmsEditFieldLabel   matlab.ui.control.Label
        TwistcalrevEditField            matlab.ui.control.NumericEditField
        TwistcalrevEditFieldLabel       matlab.ui.control.Label
        StartsimulatieButton            matlab.ui.control.Button
        CORIOLISKRACHTSwitch            matlab.ui.control.Switch
        CORIOLISKRACHTSwitchLabel       matlab.ui.control.Label
        MagnuskrachtfactorEditField     matlab.ui.control.NumericEditField
        MagnuskrachtfactorEditFieldLabel  matlab.ui.control.Label
        YawweerstandsfactorEditField    matlab.ui.control.NumericEditField
        YawweerstandsfactorEditFieldLabel  matlab.ui.control.Label
        LiftfactorEditField             matlab.ui.control.NumericEditField
        LiftfactorEditFieldLabel        matlab.ui.control.Label
        VormfunctieEditField            matlab.ui.control.NumericEditField
        VormfunctieEditFieldLabel       matlab.ui.control.Label
        FITTINGFACTORENLabel            matlab.ui.control.Label
        Dichtheidluchtkgm3EditField     matlab.ui.control.NumericEditField
        Dichtheidluchtkgm3EditFieldLabel  matlab.ui.control.Label
        TemperatuurCEditField           matlab.ui.control.NumericEditField
        TemperatuurCEditFieldLabel      matlab.ui.control.Label
        StopafstandEditField            matlab.ui.control.NumericEditField
        StopvoorwaardeDropDown          matlab.ui.control.DropDown
        MassaprojectielgEditField       matlab.ui.control.NumericEditField
        StopafstandEditFieldLabel       matlab.ui.control.Label
        StopvoorwaardeDropDownLabel     matlab.ui.control.Label
        MassaprojectielgEditFieldLabel  matlab.ui.control.Label
        V_0msEditField                  matlab.ui.control.NumericEditField
        V_0msEditFieldLabel             matlab.ui.control.Label
        ElevatiehoekEditField           matlab.ui.control.NumericEditField
        ElevatiehoekLabel               matlab.ui.control.Label
        BaanmodelDropDown               matlab.ui.control.DropDown
        BaanmodelDropDownLabel          matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        EindebaanLabel                  matlab.ui.control.Label
        StopvoorwaardeLabel             matlab.ui.control.Label
        SchuintehoekEditField_2         matlab.ui.control.NumericEditField
        SchuintehoekEditField_2Label    matlab.ui.control.Label
        VluchttijdsEditField_2          matlab.ui.control.NumericEditField
        VluchttijdsEditField_2Label     matlab.ui.control.Label
        ResterendesnelheidmsEditField_2  matlab.ui.control.NumericEditField
        ResterendesnelheidmsEditField_2Label  matlab.ui.control.Label
        PijlmEditField_2                matlab.ui.control.NumericEditField
        PijlmEditField_2Label           matlab.ui.control.Label
        DrachtmEditField_2              matlab.ui.control.NumericEditField
        DrachtmEditField_2Label         matlab.ui.control.Label
        DriftmEditField_2               matlab.ui.control.NumericEditField
        DriftmEditField_2Label          matlab.ui.control.Label
        SchuintehoekEditField           matlab.ui.control.NumericEditField
        SchuintehoekLabel               matlab.ui.control.Label
        VluchttijdsEditField            matlab.ui.control.NumericEditField
        VluchttijdsEditFieldLabel       matlab.ui.control.Label
        ResterendesnelheidmsEditField   matlab.ui.control.NumericEditField
        ResterendesnelheidmsEditFieldLabel  matlab.ui.control.Label
        PijlmEditField                  matlab.ui.control.NumericEditField
        PijlmEditFieldLabel             matlab.ui.control.Label
        DrachtmEditField                matlab.ui.control.NumericEditField
        DrachtmEditFieldLabel           matlab.ui.control.Label
        DriftmEditField                 matlab.ui.control.NumericEditField
        DriftmEditFieldLabel            matlab.ui.control.Label
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
         V0 % Beginsnelheid [m/s]
         Incl % Elevatie hoek [rad]
         mp % Massa projectiel [kg]
         H % twist
         % fitting factoren
         i
         f_L
         Q_D
         Q_M
         
         % Stop
         stop % richting van de stopconditie
         limit % afstand voor de stopconditie

         % Coriolis
         C_toggle
         
         % natuur
         T %[K]
         rho %[kg/m^3]

         % Wind
         Wx %[m/s]
         Wy %[m/s]
         Wz %[m/s]
         W_toggle

         % Positie van het vuren
         Latitude
         Azimuth
         % het soort traject
         Traj
    end
    
    methods (Access = private)

%----------------------------------------------------------------------------
        % Parabolic        
        function [x,y] = Parabolic(app)

            % Extra gegevens voor parabolisch model
            g = 9.81; %[m/s^2]
            d = 7.62e-3; %[m]


            X_parabool = linspace(0,50000,10000);
            
            Y_parabool = X_parabool*tan(app.Incl) - g/(2*app.V0.^2 * (cos(app.Incl))^2).* X_parabool.^2;
            
            
            x = X_parabool';
            y = Y_parabool';
            
        end
%----------------------------------------------------------------------------
        % PMM

        function CD = CD_calc(app,M,var)
            if M < 1 && var == true
                CD = 0.16;
            elseif M >= 1 && var == true
                CD  = 0.8*(0.5 - (M-1)/10);
            else
                CD = 0.36;
            end
        end

        function [x,y,U] = PMM(app,Incl,V0,rho,T,m)

            VX0 = V0*cos(Incl); % Snelheid in X 
            VY0 = V0*sin(Incl); % Snelheid in Y
            DT = [0.0001]; % tijdstap [s]
            c = sqrt(rho*T*287.11); % geluidsnelheid [m/s]
            
            g = 9.81; % Valversnelling [m/s^2]
            d = 7.62e-3; % Kaliber
            for dt = DT
                
                CD = [0.16];
                % Initiële waarden
                t = [0];
                x = [0];
                y = [0];
                
                U = [V0];
                ux = [VX0];
                uy = [VY0];
                
                k = pi*d^2 *rho/(8*m); 
                i = 1;
                
                % Discretisatie
                while y(end)>=0
                    i = i+1;
                
                    t(i) = t(i-1) + dt;
                    CD0 =CD_calc(app,U(i-1)/c,true);
                    CD(i) = CD0;
                    ux(i) = ux(i-1) - (k*CD0*U(i-1)*ux(i-1))*dt;
                    uy(i) = uy(i-1) - (g+k*CD0*U(i-1)*uy(i-1))*dt;
                
                    x(i) = x(i-1) + ux(i-1)*dt;
                    y(i) = y(i-1) + uy(i-1)*dt;
                
                    U(i) = sqrt(ux(i)^2 + uy(i)^2);
                end
            end
        end

% ---------------------------------------------------------------------------
        % EXTRA FUNCTIES VOOR MPMM
            
        function CD0 = CD0_calc(app,M,matrix)
            if M <= 0.6
                CD0 = matrix(1,2);
            elseif M >= 2.59  
                CD0 = matrix(end,2);
            else
                index = find(matrix(:,1) == round(M,2));
                CD0 = matrix(index,2);
            end
        end
        
        function CD2 = CD2_calc(app,M,matrix)
            if M <= 0.61
                CD2 = matrix(1,2);
            elseif M >= 2.29  
                CD2 = matrix(end,2);
            else
                index = find(matrix(:,1) == round(M,2));
                CD2 = matrix(index,2);
            end
        end
        
        function CMA = CMA_calc(app,M,matrix)
            if M <= 0.62
                CMA = matrix(1,2);
            elseif M >= 2.29  
                CMA = matrix(end,2);
            else
                index = find(matrix(:,1) == round(M,2));
                CMA = matrix(index,2);
            end
        end
        
        function CLA = CLA_calc(app,M,matrix)
            if M <= 0.62
                CLA = matrix(1,2);
            elseif M >= 2.29  
                CLA = matrix(end,2);
            else
                index = find(matrix(:,1) == round(M,2));
                CLA = matrix(index,2);
            end
        end
        
        
        function CLP = CLP_calc(app,M)
        
        CLP = -0.035 + M/150;
        
        end
        
        
        function CPn = CPN_calc(app,a, matrix)
                Ma = matrix(:,1);
                CPn_arr = matrix(:,2);
                if a < Ma(1)
                    CPn = CPn_arr(1);
                elseif a > Ma(end)
                    CPn = CPn_arr(end);
                elseif isempty(find(a == Ma)) == 0
                    CPn = CPn_arr(find(a == Ma));
                else
                    I1 = find(Ma < a);
                    i1 = I1(end);
                    I2 = find(Ma > a); i2 = I2(1);
            
                    cpn1 = CPn_arr(i1);
                    cpn2 = CPn_arr(i2);
                    Ma1 = Ma(i1);
                    Ma2 = Ma(i2);
                    CPn = cpn1 + (a - Ma1)*(cpn2 - cpn1)/(Ma2 - Ma1);
                end
        end

% ---------------------------------------------------------------------------
        % MPMM 
        function [error,n,n_stop,X,dracht,V,delta_alpha, pijl, drift, resterende_snelheid, vluchttijd, schuintehoek] = MPMM(app,V0,phi,m,rho,...
                T,i,f_L,Q_D,Q_M,stop,limit,C_toggle,W,H)
            CD0_DATA = [0.6 0.15;0.61 0.15;0.62 0.13;0.63 0.13;0.64 0.13;0.65 0.13;0.66 0.13;0.67 0.13;0.68 0.13;0.69 0.13];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
            CD2_DATA = [0.61 3.06;0.62 3.06;0.63 3.06;0.64 3.06;0.65 3.06;0.66 3.07;0.67 3.07;0.68 3.07;0.69 3.08;0.7 3.08];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
            CMA_DATA = [0.62 3.24;0.63 3.26;0.64 3.28;0.65 3.31;0.66 3.32;0.67 3.34;0.68 3.34;0.69 3.35;0.7 3.36;0.71 3.37];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
            CLA_DATA = [0.62 1.64;0.63 1.63;0.64 1.61;0.65 1.6;0.66 1.59;0.67 1.58;0.68 1.58;0.69 1.57;0.7 1.56;0.71 1.55];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
            CPN_DATA = [0.798 3.65;0.803 3.95;1.086 3.24;1.098 3.27;1.395 2.88;1.407 2.92;1.801 2.84;1.848 2.76;2.181 2.68;2.184 2.68;2.191 2.66];
            
            
            % Parameters nodig voor de simulatie
            d = 7.62e-3; % diameter projectiel [m]
            Ix = 6*10^-8; % Hoofdtraagheidsmoment [kgm^2]
            c = sqrt(1.4*287.11*T); % Geluidssnelheid [m/s]
            latitude = app.Latitude * pi/180; % *pi/180 Latitude [rad]
            AZ = app.Azimuth; % Azimuth [MILS]
            OMEGA = 7.292115*10^-5; % Constante rotatie aarde [rad/s]


            % INITIALISATIE 
            
            S = pi*(d/2)^2; % Doorsnede cilinder
            p0 = (2*pi*V0)/(H*d); % Startspin
            g = -9.80665*(1-0.0026*cos(2*latitude)); % valversnelling
            dt = 10^-4; % Tijdstap
            n_max = 1e7; % Max iteraties
            p = zeros(1,n_max); % Spin
            delta_alpha = zeros(3,n_max); % Schuinte hoek. 
            X = zeros(3,n_max); % positie
            V = zeros(3,n_max); % snelheid (lucht)
            V_dot = zeros(3,1); % versnelling (lucht) 
            U = zeros(3,n_max); % snelheid (grond)
            
            
            % Initiële waarden
            p(1) = p0;
            V(1,1) = V0*cos(phi);
            V(2,1) = V0*sin(phi);        
            TOF_stop = 0; 
            TOF = 0;
            n = 0; 
            n_stop = 1; 
            t = 0; 
            done = false;
            error = false;
            
            % Berekenen van MPMM
            while true
            
                % Volgende tijdstap
                n = n+1;
                t = t+1;
            
                % Mach getal
                v = norm(V(:,n));
                M = v/c;
            
                % Bepalen van de coëfficiënten 
                CD0 = CD0_calc(app,M,CD0_DATA);
                CDA2 = CD2_calc(app,M,CD2_DATA);
                CLA = CLA_calc(app,M,CLA_DATA);
                CLA3 = 0;
                CNpA = CPN_calc(app,M,CPN_DATA);
                CMA = CMA_calc(app,M,CMA_DATA);
                CLP = CLP_calc(app,M);
            
                % Verandering in axial spin (forward Euler)
                p_dot = (1/2*rho*v^2) * S * (p(n)*d/v) * d * CLP / Ix;
                p(n+1) = p(n) + p_dot*dt;
                
                % yaw of repose
                yaw_repose = norm(delta_alpha(:,n));
                delta_alpha(:,n+1) =  -8 * Ix * p(n) * cross( V(:,n),V_dot )/( pi * rho * d^3 * CMA * v^4 );
                
                % drag kracht
                DF_m = -(( pi * rho * i * d^2 ) / ( 8 * m )) ...
                        * ( CD0 + (CDA2 * ( Q_D * yaw_repose )^2 )) * v * V(:,n);
                    
                % lift kracht
                LF_m = (( pi * rho * d^2 * f_L ) / ( 8 * m )) ...
                        * ( CLA + CLA3 * yaw_repose^2 ) * v^2 * delta_alpha(:,n) ;
                
                % Magnus force
                MF_m = -(( pi * rho * d^3 * Q_M * p(n) * CNpA ) / ( 8 * m )) ...
                        * cross( delta_alpha(:,n),V(:,n) );
                
                % Wind
                if app.W_toggle == "false"
                    W = [0;0;0];
                end

                % Coriolis kracht
                if C_toggle == "false"
                    lambda_M = [0;0;0];
          
                else
                    omega = [ OMEGA * cos(latitude) * cos(AZ);
                              OMEGA * sin(latitude);
                             -OMEGA * cos(latitude) * sin(AZ)];
                    lambda_M = -2*(cross(omega,U(:,n)));

                    
                end
                       
                % Verandering in snelheid
                V_dot = DF_m + LF_m + MF_m + [0;g;0] + lambda_M;
                
                % Bepalen Snelheid (forward Euler)
                V(:,n+1) = V(:,n) + V_dot*dt;
                U(:,n+1) = V(:,n) + W;
                X(:,n+1) = X(:,n) + U(:,n)*dt;
            
            
                % Stopcriterium nagaan
                if stop == "X" 
                    if ~done
                        if X(1,n+1) > limit
                            if abs(X(1,n)-limit) < 10^-3
                                TOF_stop = TOF_stop + dt*t;
                                TOF = TOF_stop;
                                t = 0;
                                n_stop = n;
                                dt = 10^-4;
                                done = true;
                            else
                                TOF_stop = TOF_stop + dt*(t-1);
                                t = 0;
                                dt = dt/2;
                                n = n-1;
                            end
                        end
                    end  
                elseif stop == "Y"
                    if X(2,n+1) < X(2,n) % Projectiel begint te dalen
                        if ~done
                            if limit <= max(X(2,1:n))
                                if X(2,n+1) < limit
                                    if abs(X(2,n)-limit) < 10^-3
                                        TOF_stop = TOF_stop+dt*t;
                                        TOF = TOF_stop;
                                        t = 0;
                                        n_stop = n;
                                        dt = 10^-4;
                                        done = true;
                                    else
                                        TOF_stop = TOF_stop+dt*(t-1);
                                        t = 0;
                                        dt = dt/2;
                                        n = n-1;
                                    end
                                end
                            else
                                msgbox("De gevraagde hoogte is te hoog, probeer een lagere hoogte.",'MPMM','error');
                                error = true;
                                done = true;
                                dracht = [X(1,n_stop),X(1,n)]; 
                                pijl = [max(X(2,1:n_stop)),max(X(2,1:n))];
                                drift = [X(3,n_stop),X(3,n)]; 
                                resterende_snelheid = [norm(V(:,n_stop)),norm(V(:,n))];
                                vluchttijd = [TOF_stop,TOF];
                                schuintehoek = [norm(delta_alpha(:,n_stop)),norm(delta_alpha(:,n))];
                            end
                        end
                    end
                
                end
                
                % einde vlucht
                if X(2,n+1) < 0
                    if abs(X(2,n)-0) < 10^-3
                        TOF = TOF+dt*t;
                        break
                    else
                        TOF = TOF+dt*(t-1);
                        t = 0;
                        dt = dt/2;
                        n = n-1;
                    end 
                end
                
                % maximum itteraties
                if n == n_max
                    break
                end
            end

            if stop == "X" && limit >= X(1,n)
                dracht = [X(1,n_stop),X(1,n)]; 
                pijl = [max(X(2,1:n_stop)),max(X(2,1:n))];
                drift = [X(3,n_stop),X(3,n)]; 
                resterende_snelheid = [norm(V(:,n_stop)),norm(V(:,n))];
                vluchttijd = [TOF_stop,TOF];
                schuintehoek = [norm(delta_alpha(:,n_stop)),norm(delta_alpha(:,n))];
                error = true;
                msgbox("De gevraagde dracht is te groot, probeer een kortere dracht.",'MPMM','error');
            elseif error == false
                % NUMERIEKE OUTPUT
                dracht = [X(1,n_stop),X(1,n)]; 
                pijl = [max(X(2,1:n_stop)),max(X(2,1:n))];
                drift = [X(3,n_stop),X(3,n)]; 
                resterende_snelheid = [norm(V(:,n_stop)),norm(V(:,n))];
                vluchttijd = [TOF_stop,TOF];
                schuintehoek = [norm(delta_alpha(:,n_stop)),norm(delta_alpha(:,n))];
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: StartsimulatieButton
        function StartsimulatieButtonPushed(app, event)
            tic
            % Input wapen
            app.V0 = app.V_0msEditField.Value;
            app.Incl = app.ElevatiehoekEditField.Value * pi/180;
            app.mp = app.MassaprojectielgEditField.Value * 10^-3; % [kg]
            app.H = app.TwistcalrevEditField.Value;

            % fitting factoren
            app.i = app.VormfunctieEditField.Value; 
            app.f_L = app.LiftfactorEditField.Value;
            app.Q_D = app.YawweerstandsfactorEditField.Value;
            app.Q_M = app.MagnuskrachtfactorEditField.Value;
            
            % Stop
            app.stop = app.StopvoorwaardeDropDown.Value;
            app.limit = app.StopafstandEditField.Value;
            
            % Coriolis
            app.C_toggle = string(app.CORIOLISKRACHTSwitch.Value);           

            % Omgeving
            app.T = app.TemperatuurCEditField.Value + 273.15;
            app.rho = app.Dichtheidluchtkgm3EditField.Value;
            
            % Wind
            app.Wx = app.WindXrichtingmsEditField.Value;
            app.Wy = app.WindYrichtingmsEditField.Value;
            app.Wz = app.WindZrichtingmsEditField.Value;
            app.W_toggle = string(app.WINDSwitch.Value);
            W = [app.Wx; app.Wy; app.Wz];

            % Positie van het vuren 
            app.Latitude = app.LatitudeEditField.Value;
            app.Azimuth = app.AzimutmilsEditField.Value;


            if app.Traj == 1
                % PARABOLISCH
                [x,y] = Parabolic(app);

                % De OUTPUT
                plot(app.UIAxes,x(1:length(y)),y,'b','Linewidth',2)
                ylim(app.UIAxes,[0 inf]),
                XSTOP = find(abs(y)<1);
                plot(app.UIAxes_2,0,0)
                plot(app.UIAxes_3,0,0)
                plot(app.UIAxes_5,0,0)
                % Stopvoorwaarde
                app.DriftmEditField.Value = 0;
                app.PijlmEditField.Value = 0;
                app.DrachtmEditField.Value = 0;
                app.ResterendesnelheidmsEditField.Value = 0;
                app.VluchttijdsEditField.Value = 0;
                app.SchuintehoekEditField.Value = 0;
                % Impact
                app.DriftmEditField_2.Value = 0;
                app.PijlmEditField_2.Value = 0;
                app.DrachtmEditField_2.Value = x(XSTOP(end));
                app.ResterendesnelheidmsEditField_2.Value = 0;
                app.VluchttijdsEditField_2.Value = 0;
                app.SchuintehoekEditField_2.Value = 0;
            elseif app.Traj == 2
                % PMM
                [x,y,U] = PMM(app,app.Incl,app.V0,app.rho,app.T,app.mp);

                % De OUTPUT

                % Snelheid
                plot(app.UIAxes_3,x,U,'b','Linewidth',2)
                
                % X-Y PLOT
                plot(app.UIAxes,x,y,'b','Linewidth',2)
                
                plot(app.UIAxes_5,0,0)
                plot(app.UIAxes_2,0,0)
                
                % Stopvoorwaarde
                app.DriftmEditField.Value = 0;
                app.PijlmEditField.Value = 0;
                app.DrachtmEditField.Value = 0;
                app.ResterendesnelheidmsEditField.Value = 0;
                app.VluchttijdsEditField.Value = 0;
                app.SchuintehoekEditField.Value = 0;
                
                % Impact
                app.DriftmEditField_2.Value = 0;
                app.PijlmEditField_2.Value = max(y);
                app.DrachtmEditField_2.Value = x(length(U));
                app.ResterendesnelheidmsEditField_2.Value = U(end);
                app.VluchttijdsEditField_2.Value = x(length(U))/U(end);
                app.SchuintehoekEditField_2.Value = 0;

            elseif  app.Traj == 3
                % MPMM
                [error,n,n_stop,X,dracht,V,delta_alpha, pijl, drift, resterende_snelheid, vluchttijd, schuintehoek] = MPMM(app,app.V0,app.Incl,app.mp,app.rho,app.T,app.i,app.f_L,app.Q_D,app.Q_M,app.stop,app.limit,app.C_toggle,W,app.H);
                

                % De OUTPUT
                if error == false
                    % X-Y PLOT
                    plot(app.UIAxes,X(1,1:n),X(2,1:n),'b','Linewidth',2)
                    hold(app.UIAxes,"on")
                    plot(app.UIAxes,X(1,n_stop),X(2,n_stop),'r.','MarkerSize',25)
                    plot(app.UIAxes,[0,X(1,n_stop)],[X(2,n_stop),X(2,n_stop)],'r--')
                    plot(app.UIAxes,[X(1,n_stop),X(1,n_stop)],[0,X(2,n_stop)],'r--')               
                    hold(app.UIAxes,"off")
                    
    
                    % X-Z PLOT
                    plot(app.UIAxes_2,X(3,1:n),X(1,1:n),'b','Linewidth',2)
                    hold(app.UIAxes_2,"on")
                    plot(app.UIAxes_2,X(3,n_stop),X(1,n_stop),'r.','MarkerSize',25)
                    plot(app.UIAxes_2,[0,X(3,n_stop)],[X(1,n_stop),X(1,n_stop)],'r--')
                    plot(app.UIAxes_2,[X(3,n_stop),X(3,n_stop)],[0,X(1,n_stop)],'r--')               
                    hold(app.UIAxes_2,"off")
    
                    % Yaw PLOT
    
                    plot(app.UIAxes_5,X(1,1:n),sqrt(sum(delta_alpha(:,1:n).^2,1)),'b','Linewidth',2)
                    hold(app.UIAxes_5,"on")
                    plot(app.UIAxes_5,X(1,n_stop),sqrt(sum(delta_alpha(:,n_stop).^2,1)),'r.','MarkerSize',25)
                    plot(app.UIAxes_5,[0,X(1,n_stop)],[sqrt(sum(delta_alpha(:,n_stop).^2,1)),sqrt(sum(delta_alpha(:,n_stop).^2,1))],'r--')
                    plot(app.UIAxes_5,[X(1,n_stop),X(1,n_stop)],[0,sqrt(sum(delta_alpha(:,n_stop).^2,1))],'r--')               
                    hold(app.UIAxes_5,"off")
                    
    
                    % Snelheid
                    %plot(app.UIAxes_3,X(1,1:n_stop),sqrt(sum(V(:,1:n_stop).^2,1)))
                    plot(app.UIAxes_3,X(1,1:n),sqrt(sum(V(:,1:n).^2,1)),'b','Linewidth',2)
                    hold(app.UIAxes_3,"on")
                    plot(app.UIAxes_3,X(1,n_stop),sqrt(sum(V(:,n_stop).^2,1)),'r.','MarkerSize',25)
                    plot(app.UIAxes_3,[0,X(1,n_stop)],[sqrt(sum(V(:,n_stop).^2,1)),sqrt(sum(V(:,n_stop).^2,1))],'r--')
                    plot(app.UIAxes_3,[X(1,n_stop),X(1,n_stop)],[0,sqrt(sum(V(:,n_stop).^2,1))],'r--')               
                    hold(app.UIAxes_3,"off")
                    
                    % Deze waardes zullen arrays zijn met op de eerste
                    % plaats de waarde voor de stopvoorwaarde en als 2de
                    % waarde de data voor de impact

                    % Stopvoorwaarde
                    app.DriftmEditField.Value = drift(1);
                    app.PijlmEditField.Value = pijl(1);
                    app.DrachtmEditField.Value = dracht(1);
                    app.ResterendesnelheidmsEditField.Value = resterende_snelheid(1);
                    app.VluchttijdsEditField.Value = vluchttijd(1);
                    app.SchuintehoekEditField.Value = schuintehoek(1)*180/pi;
                    
                    % Impact
                    app.DriftmEditField_2.Value = drift(2);
                    app.PijlmEditField_2.Value = pijl(2);
                    app.DrachtmEditField_2.Value = dracht(2);
                    app.ResterendesnelheidmsEditField_2.Value = resterende_snelheid(2);
                    app.VluchttijdsEditField_2.Value = vluchttijd(2);
                    app.SchuintehoekEditField_2.Value = schuintehoek(2)*180/pi;
                end
            end   
            disp('Duration of calculations:')
            toc      
        end

        % Clicked callback: BaanmodelDropDown
        function BaanmodelDropDownClicked(app, event)
            app.Traj = event.InteractionInformation.Item-1;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {780, 780};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {391, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BaanmodelDropDownLabel = uilabel(app.LeftPanel);
            app.BaanmodelDropDownLabel.Text = 'Baanmodel';
            app.BaanmodelDropDown = uidropdown(app.LeftPanel);
            app.BaanmodelDropDown.Items = {'Selecteer model', 'Parabolisch', 'PMM', 'MPMM'};
            app.BaanmodelDropDown.ItemsData = {'0', '1', '2', '3'};
            app.BaanmodelDropDown.ClickedFcn = createCallbackFcn(app, @BaanmodelDropDownClicked, true);
            app.BaanmodelDropDown.Value = '0';
            app.ElevatiehoekLabel = uilabel(app.LeftPanel);
            app.ElevatiehoekLabel.Text = 'Elevatie hoek [°]';
            app.ElevatiehoekEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ElevatiehoekEditField.Value = 1;
            app.V_0msEditFieldLabel = uilabel(app.LeftPanel);
            app.V_0msEditFieldLabel.Text = 'V_0 [m/s]';
            app.V_0msEditField = uieditfield(app.LeftPanel, 'numeric');
            app.V_0msEditField.Limits = [10 3000];
            app.V_0msEditField.Value = 830;
            app.MassaprojectielgEditFieldLabel = uilabel(app.LeftPanel);
            app.MassaprojectielgEditFieldLabel.Text = 'Massa projectiel [g]';
            app.StopvoorwaardeDropDownLabel = uilabel(app.LeftPanel);
            app.StopvoorwaardeDropDownLabel.Text = 'Richting stopvoorwaarde';
            app.StopafstandEditFieldLabel = uilabel(app.LeftPanel);
            app.StopafstandEditFieldLabel.Text = 'Stop afstand [m]';
            app.MassaprojectielgEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MassaprojectielgEditField.Value = 9.5;
            app.StopvoorwaardeDropDown = uidropdown(app.LeftPanel);
            app.StopvoorwaardeDropDown.Items = {'X', 'Y'};
            app.StopvoorwaardeDropDown.Value = 'X';
            app.StopafstandEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StopafstandEditField.Value = 1;
            app.TemperatuurCEditFieldLabel = uilabel(app.LeftPanel);
            app.TemperatuurCEditFieldLabel.Text = 'Temperatuur [°C]';
            app.TemperatuurCEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TemperatuurCEditField.Value = 20;
            app.Dichtheidluchtkgm3EditFieldLabel = uilabel(app.LeftPanel);
            app.Dichtheidluchtkgm3EditFieldLabel.Text = 'Dichtheid lucht [kg/m^3]';
            app.Dichtheidluchtkgm3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Dichtheidluchtkgm3EditField.Value = 1.225;
            app.FITTINGFACTORENLabel = uilabel(app.LeftPanel);
            app.FITTINGFACTORENLabel.Text = 'FITTING FACTOREN';
            app.VormfunctieEditFieldLabel = uilabel(app.LeftPanel);
            app.VormfunctieEditFieldLabel.Text = 'Vormfunctie';
            app.VormfunctieEditField = uieditfield(app.LeftPanel, 'numeric');
            app.VormfunctieEditField.Value = 1;
            app.LiftfactorEditFieldLabel = uilabel(app.LeftPanel);
            app.LiftfactorEditFieldLabel.Text = 'Lift factor ';
            app.LiftfactorEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LiftfactorEditField.Value = 1;
            app.YawweerstandsfactorEditFieldLabel = uilabel(app.LeftPanel);
            app.YawweerstandsfactorEditFieldLabel.Text = 'Yaw weerstandsfactor';
            app.YawweerstandsfactorEditField = uieditfield(app.LeftPanel, 'numeric');
            app.YawweerstandsfactorEditField.Value = 1;
            app.MagnuskrachtfactorEditFieldLabel = uilabel(app.LeftPanel);
            app.MagnuskrachtfactorEditFieldLabel.Text = 'Magnuskracht factor';
            app.MagnuskrachtfactorEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MagnuskrachtfactorEditField.Value = 1;
            app.CORIOLISKRACHTSwitchLabel = uilabel(app.LeftPanel);
            app.CORIOLISKRACHTSwitchLabel.Text = 'CORIOLIS KRACHT';
            app.CORIOLISKRACHTSwitch = uiswitch(app.LeftPanel, 'slider');
            app.CORIOLISKRACHTSwitch.ItemsData = {'false', 'true'};
            app.CORIOLISKRACHTSwitch.Value = 'false';
            app.StartsimulatieButton = uibutton(app.LeftPanel, 'push');
            app.StartsimulatieButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulatieButtonPushed, true);
            app.StartsimulatieButton.Text = 'Start simulatie';
            app.TwistcalrevEditFieldLabel = uilabel(app.LeftPanel);
            app.TwistcalrevEditFieldLabel.Text = 'Twist [cal/rev]';
            app.TwistcalrevEditField = uieditfield(app.LeftPanel, 'numeric');
            app.TwistcalrevEditField.Value = 39;
            app.WindXrichtingmsEditFieldLabel = uilabel(app.LeftPanel);
            app.WindXrichtingmsEditFieldLabel.Text = 'Wind (X richting) [m/s]';
            app.WindXrichtingmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WindZrichtingmsEditFieldLabel = uilabel(app.LeftPanel);
            app.WindZrichtingmsEditFieldLabel.Text = 'Wind (Z richting) [m/s]';
            app.WindZrichtingmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WindYrichtingmsEditFieldLabel = uilabel(app.LeftPanel);
            app.WindYrichtingmsEditFieldLabel.Text = 'Wind (Y richting) [m/s]';
            app.WindYrichtingmsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WINDSwitchLabel = uilabel(app.LeftPanel);
            app.WINDSwitchLabel.Text = 'WIND';
            app.WINDSwitch = uiswitch(app.LeftPanel, 'slider');
            app.WINDSwitch.ItemsData = {'false', 'true'};
            app.WINDSwitch.Value = 'false';
            app.LatitudeEditFieldLabel = uilabel(app.LeftPanel);
            app.LatitudeEditFieldLabel.Text = 'Latitude [°]';
            app.LatitudeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LatitudeEditField.Value = 51;
            app.AzimutmilsEditFieldLabel = uilabel(app.LeftPanel);
            app.AzimutmilsEditFieldLabel.Text = 'Azimut [mils]';
            app.AzimutmilsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'OUTPUT';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'X-Y baan projectiel (dracht)')
            xlabel(app.UIAxes, 'X positie projectiel [m]')
            ylabel(app.UIAxes, 'Y positie projectiel [m]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'X-Z baan projectiel (drift)')
            xlabel(app.UIAxes_2, 'Z positie projectiel [m]')
            ylabel(app.UIAxes_2, 'X positie projectiel [m]')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, 'Snelheid')
            xlabel(app.UIAxes_3, 'Afstand horizontaal [m]')
            ylabel(app.UIAxes_3, 'Snelheid [m/s]')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_5 = uiaxes(app.RightPanel);
            title(app.UIAxes_5, 'Schuinte hoek')
            xlabel(app.UIAxes_5, 'Afstand horizontaal [m]')
            ylabel(app.UIAxes_5, 'Schuinte hoek [rad]')
            zlabel(app.UIAxes_5, 'Z')
            app.DriftmEditFieldLabel = uilabel(app.RightPanel);
            app.DriftmEditFieldLabel.Text = 'Drift [m]';
            app.DriftmEditField = uieditfield(app.RightPanel, 'numeric');
            app.DriftmEditField.Editable = 'off';
            app.DrachtmEditFieldLabel = uilabel(app.RightPanel);
            app.DrachtmEditFieldLabel.Text = 'Dracht [m]';
            app.DrachtmEditField = uieditfield(app.RightPanel, 'numeric');
            app.DrachtmEditField.Editable = 'off';
            app.PijlmEditFieldLabel = uilabel(app.RightPanel);
            app.PijlmEditFieldLabel.Text = 'Pijl [m]';
            app.PijlmEditField = uieditfield(app.RightPanel, 'numeric');
            app.PijlmEditField.Editable = 'off';
            app.ResterendesnelheidmsEditFieldLabel = uilabel(app.RightPanel);
            app.ResterendesnelheidmsEditFieldLabel.Text = 'Resterende snelheid [m/s]';
            app.ResterendesnelheidmsEditField = uieditfield(app.RightPanel, 'numeric');
            app.ResterendesnelheidmsEditField.Editable = 'off';
            app.VluchttijdsEditFieldLabel = uilabel(app.RightPanel);
            app.VluchttijdsEditFieldLabel.Text = 'Vluchttijd [s]';
            app.VluchttijdsEditField = uieditfield(app.RightPanel, 'numeric');
            app.VluchttijdsEditField.Editable = 'off';
            app.SchuintehoekLabel = uilabel(app.RightPanel);
            app.SchuintehoekLabel.Text = 'Schuinte hoek [°]';
            app.SchuintehoekEditField = uieditfield(app.RightPanel, 'numeric');
            app.SchuintehoekEditField.Editable = 'off';
            app.DriftmEditField_2Label = uilabel(app.RightPanel);
            app.DriftmEditField_2Label.Text = 'Drift [m]';
            app.DriftmEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.DriftmEditField_2.Editable = 'off';
            app.DrachtmEditField_2Label = uilabel(app.RightPanel);
            app.DrachtmEditField_2Label.Text = 'Dracht [m]';
            app.DrachtmEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.DrachtmEditField_2.Editable = 'off';
            app.PijlmEditField_2Label = uilabel(app.RightPanel);
            app.PijlmEditField_2Label.Text = 'Pijl [m]';
            app.PijlmEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.PijlmEditField_2.Editable = 'off';
            app.ResterendesnelheidmsEditField_2Label = uilabel(app.RightPanel);
            app.ResterendesnelheidmsEditField_2Label.Text = 'Resterende snelheid [m/s]';
            app.ResterendesnelheidmsEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.ResterendesnelheidmsEditField_2.Editable = 'off';
            app.VluchttijdsEditField_2Label = uilabel(app.RightPanel);
            app.VluchttijdsEditField_2Label.Text = 'Vluchttijd [s]';
            app.VluchttijdsEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.VluchttijdsEditField_2.Editable = 'off';
            app.SchuintehoekEditField_2Label = uilabel(app.RightPanel);
            app.SchuintehoekEditField_2Label.Text = 'Schuinte hoek [°]';
            app.SchuintehoekEditField_2 = uieditfield(app.RightPanel, 'numeric');
            app.SchuintehoekEditField_2.Editable = 'off';
            app.StopvoorwaardeLabel = uilabel(app.RightPanel);
            app.StopvoorwaardeLabel.Text = 'Stopvoorwaarde';
            app.EindebaanLabel = uilabel(app.RightPanel);
            app.EindebaanLabel.Text = 'Einde baan';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_MPMM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end